classdef Swabbing_test_application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        CreateNewTestButton  matlab.ui.control.Button
        TestIDLabel          matlab.ui.control.Label
        CameraModelLabel     matlab.ui.control.Label
        DebugLabel           matlab.ui.control.Label
        DebugLabeltitle      matlab.ui.control.Label
        SavepictureButton    matlab.ui.control.Button
        TakepictureButton    matlab.ui.control.Button
        ImgAx                matlab.ui.control.UIAxes
        ColorAX              matlab.ui.control.UIAxes
    end


    properties (Access = public)
        
     RsPipeLine realsense.pipeline % link to the realsense camera 
     ImgCellArray % Cell array holding the last take image by camera {full colour, red, green ,blue} each is technically stored as a full colour image but with some of the channels set to 0
    end
    
    properties (Access = private)
        currentTestDir % the path to the current tests directory (folder)
        currentTestName % Name of the directory in which the current tests data is being stored.
        dirExist = 0  % boolean value indicating if there exists a current test directory. d
        ImgCaptured = 0;

    end
    
    methods (Access = private)
        
        
        function [Img] = captureAndSplitColourImg(app)

            app.DebugLabel.Text = "Starting pipeline";
            app.RsPipeLine.start();

            app.DebugLabel.Text = "Pipeline Start";
            RsFrameSet = app.RsPipeLine.wait_for_frames();
            app.DebugLabel.Text = "Waiting for frames";

            cf = RsFrameSet.get_color_frame(); % the pipeline reading portion of this is from intel Realsense example code 
            dd = cf.get_data();
            d = permute(reshape(dd',[3,cf.get_width(),cf.get_height()]),[3 2 1]);
            app.DebugLabel.Text = "got colour frame";
            Img = cell(1, size(d,1)); %create cell array to hold images

            Img{1} = d; %save full colour image 
            app.DebugLabel.Text = " Colour taken ";
            rImgChannel = d; % save red image 
            rImgChannel(:,:,2) = 0;
            rImgChannel(:,:,3) = 0; % simply sets all other colour channels to zero
            Img{2} = rImgChannel;

            app.DebugLabel.Text = "Red taken";

            gImgChannel = d; % save green image 
            gImgChannel(:,:,1) = 0;
            gImgChannel(:,:,3) = 0;
            Img{3} = gImgChannel;

            app.DebugLabel.Text = "Green taken";

            bImgChannel = d; % save blue image 
            bImgChannel(:,:,1) = 0;
            bImgChannel(:,:,2) = 0;
            Img{4} = bImgChannel;

            app.DebugLabel.Text = "Blue taken ";
            app.ImgCaptured = 1; % updates flag so that rest of program knows image has been captured
            
            app.RsPipeLine.stop();
            app.DebugLabel.Text = "pipeline stop";
            drawnow limitrate;
            app.DebugLabel.Text = "drawn";

            delete(RsFrameSet);

            app.DebugLabel.Text = "deleted prev frame";
        end


        function [success,testDir, testName] = CreateNewTest(app)
            % creates new test Directory with the current data and time in
            % a file friendly format. It also updates the dir exist flag to
            % notify the rest of the program that a test has been made.
            dateTimeStr = string( datetime("now",'format','dd-MM-yy_HH.mm.ss'));
            dataFilename = append("TestAt ",dateTimeStr);
            dirname = append(string(directoryGet()),"/data/");
            [success ,~,~]=mkdir(append(dirname,dataFilename));
            testDir = append(dirname,dataFilename);
            testName = dataFilename;
            app.currentTestDir = testDir;
            app.currentTestName = testName;
            app.dirExist = 1;
            
            app.TestIDLabel.Text = testName;
        end
    end
    
    methods (Access = public)
        
        function success  = SaveCurrentImg(app,Colour)
            % this function saves a specific or all colour channels of the
            % last taken image in the last created test directory.
           if app.dirExist == 1 && app.ImgCaptured % makes sure there is both image and directory to save
                if Colour == "all"
                    imwrite(app.ImgCellArray{1},append(app.currentTestDir,'/FullColourImage.png'));
                    success = 1;
                elseif Colour == "r"
                    imwrite(app.ImgCellArray{2},append(app.currentTestDir,'/RedImage.png'))
                    success = 1;
                elseif Colour == "g"
                    imwrite(app.ImgCellArray{3},append(app.currentTestDir,'/GreenImage.png'))
                    success = 1;
                elseif Colour == "b"
                    imwrite(app.ImgCellArray{3},append(app.currentTestDir,'/BlueImage.png'));
                    success = 1;
                else 
                    success = 0 ; 
                end
           else
               success = 0;
           end 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            RsCtx = realsense.context();
            Devs = RsCtx.query_devices();
            if ( numel(Devs) < 1 )
                error ( 'Not found RealSense device' );
            end

            RsDev = Devs{1};
            cn = RsDev.get_info(realsense.camera_info.name);
            delete(RsDev);
            delete(RsCtx);
            app.CameraModelLabel.Text = sprintf('rs_capture_class < Camera : %s >',cn);
            app.RsPipeLine = realsense.pipeline();

            app.CreateNewTest()

            app.ImgCellArray = captureAndSplitColourImg(app);
            imshow(app.ImgCellArray{1},'Parent',app.ImgAx);
            montage({app.ImgCellArray{2},app.ImgCellArray{3},app.ImgCellArray{4}}, 'Parent',app.ColorAX,'Size',[1 3]);
            

        end

        % Button pushed function: TakepictureButton
        function TakepictureButtonPushed(app, event)
            app.TakepictureButton.Enable = "off";
            app.ImgCellArray = captureAndSplitColourImg(app);
           
            imshow(app.ImgCellArray{1},'Parent',app.ImgAx);
            montage({app.ImgCellArray{2},app.ImgCellArray{3},app.ImgCellArray{4}}, 'Parent',app.ColorAX,'Size',[1 3]);
            
            app.TakepictureButton.Enable = "on";
        end

        % Button pushed function: SavepictureButton
        function SavepictureButtonPushed(app, event)
            app.DebugLabel.Text = string(app.SaveCurrentImg("all"));
        end

        % Button pushed function: CreateNewTestButton
        function CreateNewTestButtonPushed(app, event)
            CreateNewTest(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ColorAX = uiaxes(app.UIFigure);
            title(app.ColorAX, 'Colour Channels')
            xlabel(app.ColorAX, 'Red | Green | Blue')
            app.ColorAX.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ImgAx = uiaxes(app.UIFigure);
            title(app.ImgAx, 'Taken image')
            app.TakepictureButton = uibutton(app.UIFigure, 'push');
            app.TakepictureButton.ButtonPushedFcn = createCallbackFcn(app, @TakepictureButtonPushed, true);
            app.TakepictureButton.Text = 'Take picture';
            app.SavepictureButton = uibutton(app.UIFigure, 'push');
            app.SavepictureButton.ButtonPushedFcn = createCallbackFcn(app, @SavepictureButtonPushed, true);
            app.SavepictureButton.Text = 'Save picture';
            app.DebugLabeltitle = uilabel(app.UIFigure);
            app.DebugLabeltitle.Text = 'Debug';
            app.DebugLabel = uilabel(app.UIFigure);
            app.DebugLabel.Text = 'Default';
            app.CameraModelLabel = uilabel(app.UIFigure);
            app.CameraModelLabel.Text = 'Camera Model';
            app.TestIDLabel = uilabel(app.UIFigure);
            app.TestIDLabel.Text = 'Test ID';
            app.CreateNewTestButton = uibutton(app.UIFigure, 'push');
            app.CreateNewTestButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewTestButtonPushed, true);
            app.CreateNewTestButton.Text = 'Create New Test';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Swabbing_test_application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end